classdef imat_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        dimensionmatrixEditFieldLabel   matlab.ui.control.Label
        dimensionmatrixEditField        matlab.ui.control.NumericEditField
        tauEditFieldLabel               matlab.ui.control.Label
        tauEditField                    matlab.ui.control.NumericEditField
        rcondEditFieldLabel             matlab.ui.control.Label
        rcondEditField                  matlab.ui.control.NumericEditField
        omegaEditFieldLabel             matlab.ui.control.Label
        omegaEditField                  matlab.ui.control.NumericEditField
        StartButton                     matlab.ui.control.Button
        MatrixDropDownLabel             matlab.ui.control.Label
        MatrixDropDown                  matlab.ui.control.DropDown
        toleranceEditFieldLabel         matlab.ui.control.Label
        toleranceEditField              matlab.ui.control.NumericEditField
        maxiteractionsEditFieldLabel    matlab.ui.control.Label
        maxiteractionsEditField         matlab.ui.control.NumericEditField
        mEditFieldLabel                 matlab.ui.control.Label
        mEditField                      matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        SoundSwitchLabel                matlab.ui.control.Label
        SoundSwitch                     matlab.ui.control.RockerSwitch
        xScaleSwitchLabel               matlab.ui.control.Label
        xScaleSwitch                    matlab.ui.control.ToggleSwitch
        GithubprojectButton             matlab.ui.control.StateButton
        yScaleSwitchLabel               matlab.ui.control.Label
        yScaleSwitch                    matlab.ui.control.ToggleSwitch
        maxiteractionsKnob              matlab.ui.control.Knob
        maxiteractionsEditField_2Label  matlab.ui.control.Label
        maxiteractionsEditField_2       matlab.ui.control.NumericEditField
        RightPanel                      matlab.ui.container.Panel
        UIAxes_1                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

%             f = uifigure;
%             d = uiprogressdlg(f,'Title','Computing SVD',...
%                 'Indeterminate','on');
%         
%             % Do the SVD computation
%             svd(rand(50));
%         
%             % close the dialog box
%             close(d);

            % ***********  Interface value
            % ***********
            n = app.dimensionmatrixEditField.Value;
            tau = app.tauEditField.Value;
            rcond = app.tauEditField.Value
            nmax = app.maxiteractionsEditField.Value;
            tol = app.toleranceEditField.Value;
            omega = app.omegaEditField.Value;
            m = app.mEditField.Value;
            i = 1;
            
            
            pippoknob = app.maxiteractionsKnob.Value;
            
            
            % ***********  Matrix type
            % ***********
            matrix_type = app.MatrixDropDown.Value;
            
            % matrix 1
            if strcmp(matrix_type, 'Diagonally dominant matrix sparse')
                m = app.mEditField.Value;
                A = rand(n);
                A = A - diag(diag(A));
                s = abs(A)*(ones(n,1));
                dA = A + m*diag(s);
                A = sparse(dA); % makes a full matrix sparse

            % matrix 2
            elseif strcmp(matrix_type, 'Positive symmetric definite matrix')
                A = sprandsym(n, 1, rcond, 1);

            % matrix 3
            elseif strcmp(matrix_type, 'Positive and sparse symmetric definite matrix')
                A = sprandsym(n, tau, rcond, 1); % random symmetric sparse (with the last specific parameter being positive definite)

            % matrix 4
            elseif strcmp(matrix_type, 'Poisson''s matrix')
                A = gallery('poisson', n); % Block tridiagonal matrix from Poisson's equation (sparse)

            end
      
            
            % ***********  Cholesky parameters
            % ***********
            % We use an incomplete preconditioner - Cholesky
            % Required parameters:
            opts.type = 'ict'; % Select Cholesky with threshold dropping (ICT)
            opts.droptol = 1e-3; % Below which threshold values must be set to zero (small numbers become zero)
            % If you increase this value becomes more sparse preconditioner
            opts.shape = 'upper'; % choose the upper (RT)(R) format
            % opts is actually a struct that contains the parameters
                     
            % ***********  Construction of the linear system to be solved
            % ***********
            n = size (A, 1);
            % System setting, with solution equal to a vector of 1:
            sol = ones (n, 1);
            b = A * sol;
            % Alternatively we choose the known term with a random vector of size n:
            % b = rand (n, 1);
            x0 = zeros (n, 1);
            
            % *********** Methods
            % ***********
            tic
            [x, flag, relres, iter, resvec] = pcg (A, b, tol, nmax, [], [], x0); % we can also not insert tol and nmax because they are set by default
            time1(i) = toc;
            error1(i) = norm(sol-x)/norm(sol); % relative error
            x1(i) = {x};
            iter1(i) = iter;
            resvec1(i) = {resvec};
            
            % Evaluate whether to perform the incomplete LU according to the matrices chosen
            % setup.type = 'crout';
            % setup.milu = 'row';
            % setup.droptol = 1e-3;
            % setup.udiag = 1;
            % [L,U] = ilu(A,setup);
            
            % Matlab conjugate gradient preconditioned
            R = ichol(A, opts);
            tic
            [x, flag, relres, iter, resvec] = pcg(A,b,tol,nmax,R',R,x0);
            time1p(i) = toc;
            error1p(i) = norm(sol-x)/norm(sol); % relative error
            x1p(i) = {x};
            iter1p(i) = iter;
            resvec1p(i) = {resvec};
            
            % Method of steepest descent or stationary-phase method or saddle-point method
            tic
            [x, iter, resvec] = SelfGradient(A,b,tol,nmax,x0);
            time2(i) = toc;
            error2(i) = norm(sol-x)/norm(sol); % relative error
            x2(i) = {x};
            iter2(i) = iter;
            resvec2(i) = {resvec};
            
            % Classic preconditioned gradient
            tic
            [x, iter, resvec] = SelfPreGradient(A,b,tol,nmax,R',R,x0);
            time2p(i) = toc;
            error2p(i) = norm(sol-x)/norm(sol); % relative error
            x2p(i) = {x};
            iter2p(i) = iter;
            resvec2p(i) = {resvec};
            
            % Conjugate gradient
            tic
            [x, iter, resvec] = SelfConiugGradient(A,b,tol,nmax,x0); % we can also not insert tol and nmax because they are set by default
            time3(i) = toc;
            error3(i) = norm(sol-x)/norm(sol); % relative error
            x3(i) = {x};
            iter3(i) = iter;
            resvec3(i) = {resvec};
            
            % Preconditioned conjugate gradient
            tic
            [x, iter, resvec] = SelfPreConiugGradient(A,b,tol,nmax,R',R,x0);
            time3p(i) = toc;
            error3p(i) = norm(sol-x)/norm(sol); % relative error
            x3p(i) = {x};
            iter3p(i) = iter;
            resvec3p(i) = {resvec};
            
            % Jacobi method
            tic
            [x, iter, resvec] = Jacobi(A,b,tol,nmax,x0);
            time4(i) = toc;
            error4(i) = norm(sol-x)/norm(sol); % relative error
            x4(i) = {x};
            iter4(i) = iter;
            resvec4(i) = {resvec};
            
            % Gauss Seidel method
            tic
            [x, iter, resvec] = GaussSeidel(A,b,tol,nmax,x0);
            time5(i) = toc;
            error5(i) = norm(sol-x)/norm(sol); % relative error
            x5(i) = {x};
            iter5(i) = iter;
            resvec5(i) = {resvec};
            
            % *********** Table list
            % ***********
            timeMatrix = [time1 time1p time2 time2p time3 time3p time4 time5];
            minTime = min(min(timeMatrix));
            maxTime = max(max(timeMatrix));
            
            T.MCG = time1;
            T.MPCG = time1p;
            T.SG = time2;
            T.SPG = time2p;
            T.SCG = time3;
            T.SPCG = time3p;
            T.Jacobi = time4;
            T.GaussSeidel = time5;
            
            % Time table
            timeTable = struct2table(T)
            
            I.MCG = iter1;
            I.MPCG = iter1p;
            I.SG = iter2;
            I.SPG = iter2p;
            I.SCG = iter3;
            I.SPCG = iter3p;
            I.Jacobi = iter4;
            I.GaussSeidel = iter5;
            
            % Iteration table
            iterTable = struct2table(I)
            
            E.MCG = error1;
            E.MPCG = error1p;
            E.SG = error2;
            E.SPG = error2p;
            E.SCG = error3;
            E.SPCG = error3p;
            E.Jacobi = error4;
            E.GaussSeidel = error5;
            
            % Errors table
            errorTable = struct2table(E)
            
            % *********** Plot graphs
            % ***********
            
            % Residual charts (first graph)
            ax1 = app.UIAxes_1;
            c = ax1.Color;
            ax1.Color = 'white';
            ax1.XGrid ='on';
            ax1.XScale = app.xScaleSwitch.Value;
            ax1.YScale = app.yScaleSwitch.Value;
            
            semilogy(ax1, resvec1{i}, '--g', 'LineWidth',2);
            hold(ax1, 'on');
            semilogy(ax1, resvec1p{i}, '-g', 'LineWidth',2);
            semilogy(ax1, resvec2{i}, '--r', 'LineWidth',2);
            semilogy(ax1, resvec2p{i}, '-r', 'LineWidth',2);
            semilogy(ax1, resvec3{i}, '--b', 'LineWidth',2);
            semilogy(ax1, resvec3p{i}, '-b', 'LineWidth',2);
            semilogy(ax1, resvec4{i}, '-y', 'LineWidth',2);
            semilogy(ax1, resvec5{i}, '-m', 'LineWidth',2);
            hold(ax1, 'off');

            title(ax1,['Residual Interactive Methods DIM = ', num2str(app.dimensionmatrixEditField.Value)]);
            xlabel(ax1,'Number of iterations','FontSize',9);
            %ylabel(ax1,'My y-Axis Label','FontSize',9);
            legend(app.UIAxes_1,{'MCG','MPCG','SG','SPG','SCG','SPCG','Jacobi','GaussSeidel'},'Location','northeast','NumColumns',2);

            % Iteractions (second graph)
            ax2 = app.UIAxes_2;
            c = ax2.Color;
            ax2.Color = 'white';
            ax2.XGrid ='on';
            ax2.YScale = app.yScaleSwitch.Value;
            
            iterData = [iter1(i) iter1p(i) iter2(i) iter2p(i) iter3(i) iter3p(i) iter4(i) iter5(i)];
            c = categorical({'MCG','MPCG','SG','SPG','SCG','SPCG','Jacobi','GaussSeidel'});
            c = reordercats(c,{'MCG','MPCG','SG','SPG','SCG','SPCG','Jacobi','GaussSeidel'});
            b = bar(ax2, c, iterData);
            b.FaceColor = 'flat';
            b.CData(1,:) = [0,1,0];
            b.CData(2,:) = [0,1,0];
            b.CData(3,:) = [1,0,0];
            b.CData(4,:) = [1,0,0];
            b.CData(5,:) = [0,0,1];
            b.CData(6,:) = [0,0,1];
            b.CData(7,:) = [1,1,0];
            b.CData(8,:) = [1,0,1];
            
            title(ax2,['Iteractions DIM = ', num2str(app.dimensionmatrixEditField.Value)]);
            xlabel(ax2,'Methods','FontSize',9);
            ylabel(ax2,'Number of iteractions','FontSize',9);
            %legend(app.UIAxes_2,{'MCG','MPCG','SG','SPG','SCG','SPCG','Jacobi','GaussSeidel'},'Location','northeast','NumColumns',2);
            
            % Times (third graph)
            ax3 = app.UIAxes_3;
            c = ax3.Color;
            ax3.Color = 'white';
            ax3.XGrid ='on';
            ax3.YScale = app.yScaleSwitch.Value;
            
            timeData = [time1(i) time1p(i) time2(i) time2p(i) time3(i) time3p(i) time4(i) time5(i)];
            c = categorical({'MCG','MPCG','SG','SPG','SCG','SPCG','Jacobi','GaussSeidel'});
            c = reordercats(c,{'MCG','MPCG','SG','SPG','SCG','SPCG','Jacobi','GaussSeidel'});
            b = bar(ax3, c, timeData);
            b.FaceColor = 'flat';
            b.CData(1,:) = [0,1,0];
            b.CData(2,:) = [0,1,0];
            b.CData(3,:) = [1,0,0];
            b.CData(4,:) = [1,0,0];
            b.CData(5,:) = [0,0,1];
            b.CData(6,:) = [0,0,1];
            b.CData(7,:) = [1,1,0];
            b.CData(8,:) = [1,0,1];
            
            title(ax3,['Time, DIM = ', num2str(app.dimensionmatrixEditField.Value)]);
            xlabel(ax3,'Methods','FontSize',9);
            ylabel(ax3,'Time(s)','FontSize',9);
            %legend(app.UIAxes_2,{'MCG','MPCG','SG','SPG','SCG','SPCG','Jacobi','GaussSeidel'},'Location','northeast','NumColumns',2);
            
            % Sparse (fourth graph)
            ax4 = app.UIAxes_4;
            [i,j] = find(A);
            [m,n] = size(A);
            plot(ax4,j,i,'marker','.','markersize',6,'linestyle','none');
            title(ax4,['Sparsity pattern of the matrix with dim= ', num2str(app.dimensionmatrixEditField.Value)]);
            xlabel(ax4,['nz = ' int2str(nnz(A))]);
            set(ax4,'xlim',[0 n+1],'ylim',[0 m+1],'ydir','reverse','plotboxaspectratio',[n+1 m+1 1]);
            
%             Sparse in external figure
%             figure;
%             spy(A);

            % Play Sound when press Start button
            mute = app.SoundSwitch.Value;
            if strcmp(mute, 'On')
                [y, Fs] = audioread('audio_click.mp3');
                player = audioplayer(y, Fs);
                playblocking(player);
            end

            
        end

        % Value changed function: GithubprojectButton
        function GithubprojectButtonValueChanged(app, event)
            value = app.GithubprojectButton.Value;
            dos('explorer https://github.com/skambuilds/IMAT');
        end

        % Value changed function: xScaleSwitch
        function xScaleSwitchValueChanged(app, event)
            valueXscale = app.xScaleSwitch.Value;
            
        end

        % Value changed function: maxiteractionsKnob
        function maxiteractionsKnobValueChanged(app, event)
            value = app.maxiteractionsKnob.Value;
            app.maxiteractionsEditField_2.Value = value;
        end

        % Value changed function: maxiteractionsEditField_2
        function maxiteractionsEditField_2ValueChanged(app, event)
            value = app.maxiteractionsEditField_2.Value;
            app.maxiteractionsKnob.Value = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {780, 780};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {420, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.dimensionmatrixEditFieldLabel = uilabel(app.LeftPanel);
            app.dimensionmatrixEditFieldLabel.Text = 'dimension(matrix)';
            app.dimensionmatrixEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dimensionmatrixEditField.Value = 100;
            app.tauEditFieldLabel = uilabel(app.LeftPanel);
            app.tauEditFieldLabel.Text = 'tau';
            app.tauEditField = uieditfield(app.LeftPanel, 'numeric');
            app.tauEditField.Limits = [0.01 1];
            app.tauEditField.Value = 0.05;
            app.rcondEditFieldLabel = uilabel(app.LeftPanel);
            app.rcondEditFieldLabel.Text = 'rcond';
            app.rcondEditField = uieditfield(app.LeftPanel, 'numeric');
            app.rcondEditField.Limits = [0.01 1];
            app.rcondEditField.Value = 0.01;
            app.omegaEditFieldLabel = uilabel(app.LeftPanel);
            app.omegaEditFieldLabel.Text = 'omega';
            app.omegaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.omegaEditField.Value = 0.5;
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.MatrixDropDownLabel = uilabel(app.LeftPanel);
            app.MatrixDropDownLabel.Text = 'Matrix';
            app.MatrixDropDown = uidropdown(app.LeftPanel);
            app.MatrixDropDown.Items = {'Diagonally dominant matrix sparse', 'Positive symmetric definite matrix', 'Positive and sparse symmetric definite matrix', 'Poisson''s matrix'};
            app.MatrixDropDown.Value = 'Diagonally dominant matrix sparse';
            app.toleranceEditFieldLabel = uilabel(app.LeftPanel);
            app.toleranceEditFieldLabel.Text = 'tolerance';
            app.toleranceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.toleranceEditField.Value = 1e-12;
            app.maxiteractionsEditFieldLabel = uilabel(app.LeftPanel);
            app.maxiteractionsEditFieldLabel.Text = 'max iteractions';
            app.maxiteractionsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.maxiteractionsEditField.Limits = [0 Inf];
            app.maxiteractionsEditField.Value = 200;
            app.mEditFieldLabel = uilabel(app.LeftPanel);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.LeftPanel, 'numeric');
            app.mEditField.Value = 2;
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'logo2.png';
            app.SoundSwitchLabel = uilabel(app.LeftPanel);
            app.SoundSwitchLabel.Text = 'Sound';
            app.SoundSwitch = uiswitch(app.LeftPanel, 'rocker');
            app.GithubprojectButton = uibutton(app.LeftPanel, 'state');
            app.GithubprojectButton.ValueChangedFcn = createCallbackFcn(app, @GithubprojectButtonValueChanged, true);
            app.GithubprojectButton.Text = 'Github project';
            app.xScaleSwitchLabel = uilabel(app.LeftPanel);
            app.xScaleSwitchLabel.Text = 'xScale';
            app.xScaleSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.xScaleSwitch.Items = {'Linear', 'Log'};
            app.xScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @xScaleSwitchValueChanged, true);
            app.xScaleSwitch.Value = 'Linear';
            app.yScaleSwitchLabel = uilabel(app.LeftPanel);
            app.yScaleSwitchLabel.Text = 'yScale';
            app.yScaleSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.yScaleSwitch.Items = {'Linear', 'Log'};
            app.yScaleSwitch.Value = 'Linear';
            app.maxiteractionsKnob = uiknob(app.LeftPanel, 'continuous');
            app.maxiteractionsKnob.Limits = [0 10000];
            app.maxiteractionsKnob.ValueChangedFcn = createCallbackFcn(app, @maxiteractionsKnobValueChanged, true);
            app.maxiteractionsKnob.Value = 200;
            app.maxiteractionsEditField_2Label = uilabel(app.LeftPanel);
            app.maxiteractionsEditField_2Label.Text = 'max iteractions';
            app.maxiteractionsEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.maxiteractionsEditField_2.ValueChangedFcn = createCallbackFcn(app, @maxiteractionsEditField_2ValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_1 = uiaxes(app.RightPanel);
            title(app.UIAxes_1, 'Title')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imat_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end